classdef testapp10622 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        ChannelImportTab              matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        ScaledImportButton            matlab.ui.control.Button
        RawImport                     matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        ImportSPDChannelsButton       matlab.ui.control.Button
        UIAxes_ImportedSPDs           matlab.ui.control.UIAxes
        UIAxes_ChromDiagram           matlab.ui.control.UIAxes
        OptimizationSettingsTab       matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        OptimizationSettingsLabel     matlab.ui.control.Label
        OptimizationConstraintsLabel  matlab.ui.control.Label
        OptimizationGoalLabel         matlab.ui.control.Label
        OptimizationResultsTab        matlab.ui.container.Tab
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        %% Imported Raw Values
        userSPDsImported_Prop; % Description
        XYZUserSPDsImported_Prop;
        importedFileName_Prop;
        xyuserSPDsImported_Prop;
        wlIntUserImported_Prop

        SPDsScaled_Prop ;
        xySPDsScaled_Prop ;
        XYZSPDsScaled_Prop;
    end
    
    methods (Access = private)
        
        function PlotSPDs_Helper(app)
            plot(app.UIAxes_ImportedSPDs, 380:app.wlIntUserImported_Prop:780, app.userSPDsImported_Prop(:,1:end));
            app.UIAxes_ImportedSPDs.XLim = [380,780];
        end
        
        function PlotSPDChromDiagram_Helper(app)
            %% Rename the functions argments to be the app's properties. 
            % This is verbose but might help understand code better
            %% Add the xy coordinates of the spds to the chrom diagram plot
            scatter(app.UIAxes_ChromDiagram, app.xyuserSPDsImported_Prop(:,1), app.xyuserSPDsImported_Prop(:,2) ); %plot them
            %% Don't overwrite plot each time a new plot is added
            % previous ones Note that using "hold on" by itself will NOT work https://www.mathworks.com/help/matlab/ref/uiaxes.html
            hold(app.UIAxes_ChromDiagram,'on'); 
            %% Set axis aspect ratio to be square. This is same as "axis square"
            pbaspect(app.UIAxes_ChromDiagram,[1 1 1]);
            %% Add the spectral locus and blackbody locus
            plotChromDiagram(2,app.UIAxes_ChromDiagram); %add a 2 deg chrom diagram to the axis handle
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ImportSPDChannelsButton
        function ImportSPDChannelsButtonPushed(app, event)

            %% Nothing here is shared outside of this function unless it is added to a property in the block below
            %%Deal with ui callback
            %https://www.youtube.com/watch?v=7_IC8Xcjp4w
            [spdFileName,spdFolderPath] = uigetfile('*.xlsx'); %have user select csv file
            spdFileFullPath = strcat(spdFolderPath,'\',spdFileName); %will addresses always be \ or sometimes \\ or / or // ?
            excelSheetName = "UserSPDs";

            app.importedFileName_Prop = spdFileName;
            %% Import the data
            % Get SPDs as a matrix and determine wavelength interval with
            % the assumption that the range is from 380 to 780nm
            [app.userSPDsImported_Prop, app.wlIntUserImported_Prop]    = importUserSPDs(spdFileFullPath,excelSheetName);
            %% Convert imported SPDs to xy and XYZ for an observer
            observerChoice = 2;
            [app.xyuserSPDsImported_Prop,app.XYZUserSPDsImported_Prop] = spdsToXyXYZ(app.userSPDsImported_Prop(:,1:end), observerChoice );%get xy chromaticities for the imported spds

            %% Scale data Y10 = 100 for each channel
            % Find amount needed to scale each channel
            scalePerSPD_Temp = 100./app.XYZUserSPDsImported_Prop(:,2);
            % Create new set of SPDs that are scaled to Y10 = 100;
            app.SPDsScaled_Prop         = scalePerSPD_Temp'.*app.userSPDsImported_Prop; 
            % Create new set of XYZ for them scaled. xy should not change
            % via scaling
            [app.xySPDsScaled_Prop,app.XYZSPDsScaled_Prop] = spdsToXyXYZ(app.SPDsScaled_Prop(:,1:end) , 2 );%get xy chromaticities for the imported spds

            %% Set edit value text box to show file name and number of channels
            app.EditField.Value = num2str(width(app.userSPDsImported_Prop)) + " channels were loaded from: "  + string(spdFileName);

            %% Plot the chrom diagram
            PlotSPDChromDiagram_Helper(app);
            %% Plot raw imported data to spd figure
            PlotSPDs_Helper(app);
        end

        % Button pushed function: RawImport
        function RawImportButtonPushed(app, event)
            hold(app.UIAxes_ImportedSPDs,'off'); %replace

            plot(app.UIAxes_ImportedSPDs, 380:app.wlIntUserImported_Prop:780, app.userSPDsImported_Prop(:,1:end));
            app.UIAxes_ImportedSPDs.XLim = [380,780];
        end

        % Button pushed function: ScaledImportButton
        function ScaledImportButtonPushed(app, event)
            hold(app.UIAxes_ImportedSPDs,'off'); %replace

            scalePerSPD_Temp = 100./app.XYZUserSPDsImported_Prop(:,2);
            userSPDsScaled = scalePerSPD_Temp'.*app.userSPDsImported_Prop; 

            plot(app.UIAxes_ImportedSPDs, 380:app.wlIntUserImported_Prop:780, userSPDsScaled(:,1:end));
            app.UIAxes_ImportedSPDs.XLim = [380,780];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChannelImportTab = uitab(app.TabGroup);
            app.ChannelImportTab.Title = 'Channel Import';
            app.GridLayout = uigridlayout(app.ChannelImportTab);
            app.UIAxes_ChromDiagram = uiaxes(app.GridLayout);
            title(app.UIAxes_ChromDiagram, 'CIE Diagram')
            xlabel(app.UIAxes_ChromDiagram, 'x Chromaticity')
            ylabel(app.UIAxes_ChromDiagram, 'y Chromaticity')
            zlabel(app.UIAxes_ChromDiagram, 'Z')
            app.UIAxes_ImportedSPDs = uiaxes(app.GridLayout);
            title(app.UIAxes_ImportedSPDs, 'Imported SPD Channels')
            xlabel(app.UIAxes_ImportedSPDs, 'Wavelength (nm)')
            ylabel(app.UIAxes_ImportedSPDs, 'Radiant Watts / nm')
            zlabel(app.UIAxes_ImportedSPDs, 'Z')
            app.ImportSPDChannelsButton = uibutton(app.GridLayout, 'push');
            app.ImportSPDChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSPDChannelsButtonPushed, true);
            app.ImportSPDChannelsButton.Text = 'Import SPD Channels';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Placeholder = 'Imported File Name';
            app.RawImport = uibutton(app.GridLayout, 'push');
            app.RawImport.ButtonPushedFcn = createCallbackFcn(app, @RawImportButtonPushed, true);
            app.RawImport.Text = 'RawImport';
            app.ScaledImportButton = uibutton(app.GridLayout, 'push');
            app.ScaledImportButton.ButtonPushedFcn = createCallbackFcn(app, @ScaledImportButtonPushed, true);
            app.ScaledImportButton.Text = 'ScaledImport';
            app.OptimizationSettingsTab = uitab(app.TabGroup);
            app.OptimizationSettingsTab.Title = 'Optimization Settings';
            app.GridLayout2 = uigridlayout(app.OptimizationSettingsTab);
            app.OptimizationGoalLabel = uilabel(app.GridLayout2);
            app.OptimizationGoalLabel.Text = 'Optimization Goal';
            app.OptimizationConstraintsLabel = uilabel(app.GridLayout2);
            app.OptimizationConstraintsLabel.Text = 'Optimization Constraints';
            app.OptimizationSettingsLabel = uilabel(app.GridLayout2);
            app.OptimizationSettingsLabel.Text = 'Optimization Settings';
            app.OptimizationResultsTab = uitab(app.TabGroup);
            app.OptimizationResultsTab.Title = 'Optimization Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp10622

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end