classdef MetametricApp_Beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DataImportTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        UITable_ImportedFile            matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        Button_UseSelectedSPDs          matlab.ui.control.Button
        EditField_ImportedFileName      matlab.ui.control.EditField
        Button_ImportTable              matlab.ui.control.Button
        DataValidationTab               matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ChannelSelectionTablePanel      matlab.ui.container.Panel
        UITable_ChannelSelection        matlab.ui.control.Table
        UIAxes_ChromDiagram             matlab.ui.control.UIAxes
        UIAxes_ImportedSPDs             matlab.ui.control.UIAxes
        OptimizationSettingsTab         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        OptimizationConstraintsPanel    matlab.ui.container.Panel
        UITable_Constraints             matlab.ui.control.Table
        OptimizationGoalPanel           matlab.ui.container.Panel
        Button_RunOptimization          matlab.ui.control.Button
        EditField_MaxIterations         matlab.ui.control.NumericEditField
        MaxOptimizationIterationsLabel  matlab.ui.control.Label
        DropDown_MetricGoal             matlab.ui.control.DropDown
        WhichMetricLabel                matlab.ui.control.Label
        DropDown_MaxOrMinGoal           matlab.ui.control.DropDown
        MaximizeOrMinimizeLabel         matlab.ui.control.Label
        OptimizationResultsTab          matlab.ui.container.Tab
        Panel4                          matlab.ui.container.Panel
        Button_ExportOptimization       matlab.ui.control.Button
        UITable_OptimizationResults     matlab.ui.control.Table
        UIAxes_OptimSPD                 matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Panel6                          matlab.ui.container.Panel
        CheckBox2                       matlab.ui.control.CheckBox
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        CheckBox                        matlab.ui.control.CheckBox
        UITable2                        matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)


    end
    
    properties (Access = public)
        %% Imported Raw Values
        userSPDLibrary; % Description
        XYZuserSPDLibrary;
        importedFileName_Prop;
        xyuserSPDLibrary;
        wlIntUserImported_Prop
        %% Selected
        SPDsScaled_Prop ;
        xySPDsScaled_Prop ;
        XYZSPDsScaled_Prop;
        %% Optimization options
        % Max/Min Goal Type
%         myUiFun_Prop;
%         myUiCon_Prop;
        InitialGuessChannelPercents_Prop;

        AppFilePath;

        outputSavePath; % Description
        OutputFolder = 'SolutionOutputs'; %We save our file to this folder
        ScalePerSPD % Description
        appRootPath % Description
        ChannelsColNumSelected % Description
        MetricsEvaluated % Description
        
        ChannelMetrics % Description
        wlUserImported % Description
        ChannelMetricTable
    end
    
    methods (Access = private)


        function PlotRecentTM30Bins(app,myOptimResults)
%             % Called at the end of "run optimization"
%             %% Assume that we want to plot the most recent optim results
%             myRecentResults = myOptimResults(end);
%             hold(app.UIAxes_TM30Bins,'on');
% 
%             csBins = myRecentResults.metrics.csBins;
%             hsBins = myRecentResults.metrics.hsBins;
%             rfBins = myRecentResults.metrics.rfBins;
%             %% Create / Recreate tiledlayout for the axes
%             tl = tiledlayout(app.UIAxes_TM30Bins,3,1);
%             %% Create each tile
%             rfTile = nexttile(tl);
%             rfBinBar = bar(rfTile, [1:16], rfBins);
% 
%             csTile = nexttile(tl);
%             csBinBar = bar(csTile, csBins);
% 
%             hsTile = nexttile(tl);
%             hsBinBar = bar(hsTile, hsBins);
% 
%             %% Format each tile now. This could be condensed later or made a function
%             % rfTile
%             xTip1 = rfBinBar(1).XEndPoints;
%             yTip1 = rfBinBar(1).YEndPoints;
%             labels = string( round(rfBinBar(1).YData,2,'significant') );
%             text(rfBinBar,xTip1,yTip1,labels, 'HorizontalAlignment','center','VerticalAlignment','bottom');
%             ylabel(rfBinBar,'rf bins')
%             rfTile.XTick = [1:16];
%             
%             % csTile
%             xTip1 = csBinBar(1).XEndPoints;
%             yTip1 = csBinBar(1).YEndPoints;
%             labels = string(csBinBar, round(csBinBar(1).YData,2,'significant') );
%             text(csBinBar,xTip1,yTip1,labels, 'HorizontalAlignment','center','VerticalAlignment','bottom');
%             ylabel(csBinBar,'cs bins')
%             csTile.XTick = [1:16];
% 
%             % hsTile
%             xTip1 = hsBinBar(1).XEndPoints;
%             yTip1 = hsBinBar(1).YEndPoints;
%             labels = string( round(hsBinBar(1).YData,2,'significant') );
%             text(hsBinBar,xTip1,yTip1,labels, 'HorizontalAlignment','center','VerticalAlignment','bottom');
%             ylabel(hsBinBar,'hs bins')
%             hsTile.XTick = [1:16];

        end
        
        function PlotTM30VectorGraphic(app,myOptimResults)
%             %% Again, assume we want to plot the most recent results
%             myRecentResults = myOptimResults(end);
%             gref = myRecentResults.metrics.gref;
%             gtest = myRecentResults.metrics.gtest;
%             %%
%             hold(app.UIAxes_TM30Vector,'on');
%             title(app.UIAxes_TM30Vector,"Hue Angle Plot");
%             HueAngleAvgRefP  = plot(app.UIAxes_TM30Vector, [gref(:,1);gref(1,1)] , [gref(:,2); gref(1,2)],'-k');
%             HueAngleAvgTestP = plot(app.UIAxes_TM30Vector, [gtest(:,1);gtest(1,1)] , [gtest(:,2);gtest(1,2)], '-or');
%             legend(app.UIAxes_TM30Vector,[HueAngleAvgRefP, HueAngleAvgTestP],...
%                 {"Final Hue Angle Coords Reference", "Final hue Angle Coords Test"});
%             xlabel(app.UIAxes_TM30Vector,"a" + "'");
%             ylabel(app.UIAxes_TM30Vector,"b" + "'");
%             app.UIAxes_TM30Vector.XTick = [app.UIAxes_TM30Vector.XLim(1):5:app.UIAxes_TM30Vector.XLim(2)];
%             app.UIAxes_TM30Vector.YTick = [app.UIAxes_TM30Vector.YLim(1):5:app.UIAxes_TM30Vector.YLim(2)];
%             axis square
        end
        
        function WriteOptimResults(app, myOptimResults)
            %% I practiced this in "practiceSaveResultsToFile.mlx"
            % List out which metrics to delete
            metricDeleteList = {'sref','stest','rfBins','hsBins','csBins',...
                'gref','gtest','SpdPercents0','Solution','wl'};
            % Create scalar struct (size 1) that will be output to file by
            % deleting fields listed above
            myStructForTable = rmfield(myOptimResults.metrics,metricDeleteList);
            %% Complicated but compact way to make 16 fields from the single field for each bin group
%             myBinStructVal = cellstr([myOptimResults.metrics.rfBins'; myOptimResults.metrics.csBins'; myOptimResults.metrics.chBins'];
%             myBinName

            %% Create set of tables
            % This is the main table that has single-entry values
            myTable = struct2table(myStructForTable,'AsArray',true);
%             varsToSplit = {'t2XYZ', 't2xy','t10XYZ','t10xy'};
%             splitNames =  { {'2DegX', '2DegY','2DegZ'}, {'2Degx','2Degy'} ,...
%                 {'10DegX','10DegY','10DegZ'}, {'10Degx','10Degy'} };
%             myTable = splitvars(myTable, varsToSplit, 'NewVariableNames',splitNames);
            % 
            myUiFunTable = struct2table(myOptimResults.myUiFun,'AsArray',true);
            if myUiFunTable.minOrMax == -1
                myUiFunTable.minOrMax = "Maximize";
            elseif myUiFunTable.minOrMax == 1
                myUiFunTable.minOrMax = "Minimize";
            else
%                 error("Unknown goal type (min or max) when writing myUiFun to table");
                msgbox("Unknown goal type (min or max) when writing myUiFun to table",'Error','error');
            end


            % SPD Watts/nm
            spdTable = splitvars(table(myOptimResults.metrics.stest'));
            spdTable.Properties.VariableNames = cellstr("Wavelength(nm): " +...
                string(myOptimResults.metrics.wl) );
            % The mixture of channels used for solution
            solutionTable = splitvars(table(myOptimResults.metrics.Solution'));
            solutionTable.Properties.VariableNames = cellstr( "Channel "...
                + string( 1:numel(myOptimResults.metrics.Solution) ) + " %" );
            % The initial guess percent for each channel
            initialGuessTable = splitvars(table(myOptimResults.metrics.SpdPercents0') );
            initialGuessTable.Properties.VariableNames = cellstr( "Channel " +...
                string( 1:numel(myOptimResults.metrics.SpdPercents0)) + " Initial Guess %") ;
            % How many iterations were used for optimization run
            iterationUsedTable = table(myOptimResults.metrics.IterationStop);
            iterationUsedTable.Properties.VariableNames = "Iterations Used";
            %% We have all of our tables as 1 row and many variables. Merge them
            myTableOneCol = [iterationUsedTable, myUiFunTable,myTable, solutionTable,...
                initialGuessTable, spdTable];
            %% Make each run correspond to column instead of a row
            % SPDs are usually written column-wise 
            myTableOneCol = rows2vars(myTableOneCol);
            % By default it make variable names a column on its own. Make
            % and assign rownames of table to the variable names then
            % delete the unused column of variable names now that they are
            % row namesmyTableOneCol
            myTableOneCol.Properties.RowNames= myTableOneCol.OriginalVariableNames;
            myTableOneCol.OriginalVariableNames = [];
            %% Get optimization settings to put into a third sheet
            myProps = properties(myOptimResults.myOptimOptions);
            for prop = 1:numel(myProps)
              optionStruct.(myProps{prop}) = myOptimResults.myOptimOptions.(myProps{prop});
            end
            optionTable = splitvars( struct2table(optionStruct,'asarray',true))  ;
            optionTable.TypicalX = [];
            typicalXTable= splitvars(table(myOptimResults.myOptimOptions.TypicalX') );
            typicalXTable.Properties.VariableNames = "Typical X, CH " + string(1:numel(myOptimResults.myOptimOptions.TypicalX) );
            
            optionTable = rows2vars( [optionTable, typicalXTable] ) ;
            %% Ask user where to save
            % This code is very similar to the one used to import the data
            importFileTypes = {'*.xls;*.xlsx;*.xlsb;*.xlsm;*.csv',...
                 'SPD Files (*.xls,*.xlsx,*.xlsb,*.xlsm,*.csv)'};
            [outputFileName, outputPathName] = uiputfile(importFileTypes);
            app.outputSavePath = fullfile(outputPathName,outputFileName);
            %% If file exists, load existing runs as table and append this run to it and resave
            % Note that we output 2 sheets. First sheet is the metrics per
            % run and 2nd sheet is the metric constraints table. 
            
            switch exist(app.outputSavePath,'file')
                case 0 %file does not exist yet
                    %write out merged table
                    myTableOneCol.Properties.VariableNames = "Run 1";
                    writetable(myTableOneCol,...
                        app.outputSavePath, 'Sheet',1,'WriteRowNames',true,...
                        'WriteVariableNames',true);

                    writetable(myOptimResults.myConstraintsTable,...
                        app.outputSavePath, 'Sheet',2);

                    writetable(optionTable,...
                        app.outputSavePath, 'Sheet',3);
                case 2 %it exists and is a file
                    %perhaps warnn that it will be rewritten?
                otherwise
%                     error("Unexpected case when checking is the output file already exists");
                    msgbox("Unexpected case when checking is the output file already exists",'Error','error');
            end

        end
        
        function PlotOptimSPD(app,myOptimResults)
            hold(app.UIAxes_OptimSPD,'off'); %replace each time
            plot(app.UIAxes_OptimSPD,...
                myOptimResults(end).metrics.wl, myOptimResults(end).metrics.s); %plot most recent
        end

        function saveMyDefaults(app)
            %write values to myDefaults before saving
            myDefaults = [];
            save('MyAppDefaultValues.mat','myDefaults');
        end
        
        function loadMyDefaults(app)
            load('MyAppDefaultValues.mat','myDefaults');
        end
        
        function [SpdMixOut, myOptimOptions,iterationStop,myUiFun] = RunOptimization(app)
                %% Options and edit fields are called only on this button press

                % Get table from uitable
%                 metricConstraints_table = app.UITable_Constraints.Data; %what is diff between Data & DisplayData field?
                

                %% Run Optimization

                % Make a random guess
                app.InitialGuessChannelPercents_Prop = 25+75*rand(width(app.SPDsScaled_Prop),1);
%                 spdPercents0 = app.InitialGuessChannelPercents_Prop;

                %
%                 spdChannels = app.SPDsScaled_Prop;
                % Make myUiFun structure
                myUiFun.myOptIterations = app.EditField_MaxIterations.Value; %Query edit field
                myUiFun.metric = string(app.DropDown_MetricGoal.Value); %Query dropdown selection
                switch app.DropDown_MaxOrMinGoal.Value %Convert from words to math. Minimizing the negative is maximizing
                    case 'Maximize'
                        myUiFun.minOrMax = -1;
                    case 'Minimize'
                        myUiFun.minOrMax = 1;
                end
                %% Run optimizer
                appStopFlag = false; 
                [SpdMixOut, myOptimOptions,iterationStop] = Main_Optimizer_Function_App_mfile(app.InitialGuessChannelPercents_Prop,...
                    app.SPDsScaled_Prop,...
                    app.UITable_Constraints.Data,myUiFun);
        end
        
        function structOut = shuffleMetricStruct(app,structIn,cutNum)
            %takes the 1st to cutNum index and cut shuffles it to the back
            %imagine taking a chunk of cards from a deck and shuffling them
            %once into the back
            myStartToCut = 1:1:cutNum;
            
            nFields = numel(fieldnames(structIn) );
            myCutToEnd =  cutNum+1:nFields;
            myPermOrder = [myCutToEnd, myStartToCut];

            structOut = orderfields(structIn, myPermOrder);
        end
        
      
        function  makeUIConstraintTable(app)
            %% Create Default Optimization Constraint Table

            %% Replicate metric generation done in optimziation function     
            dummyStruct = spdToTM30(ones(401,1)); %just used to get fields
            dummyStruct = spdToAlphaOpics(dummyStruct);
            %% Clumsily shuffle the bin fields to the end
            % The 48 bin fields are assigned programmatically using a
            % clever trick, but it can only be done to create a structure,
            % not add to an existing one. A workaround could make spdToTM30
            % run significantly more slowly. 
            dummyStruct = shuffleMetricStruct(app,dummyStruct,51); %move bins to back
            dummyStruct = shuffleMetricStruct(app,dummyStruct,19); %move bins to back
            %% Get the fieldnames, which are the metrics. These are stored in app properties
            app.MetricsEvaluated = fieldnames(dummyStruct);
            %% Create columns based on the number of elements
            % Set default constraints to none being used
            UseTF         = false( numel(app.MetricsEvaluated),1);
            LessThanTF    = false(numel(app.MetricsEvaluated),1);
            EqualToTF     = true(numel(app.MetricsEvaluated),1); %default
            GreaterThanTF = false(numel(app.MetricsEvaluated),1 );

            % Min > -inf, max < +inf, and equal is default to 0.
            LessThanVal    = 1*inf*ones( numel(app.MetricsEvaluated), 1);
            EqualToVal     = zeros(numel(app.MetricsEvaluated),1);
            GreaterThanVal = -1*inf*ones( numel(app.MetricsEvaluated),1);

            %% Create a standard table
            % use comma-separated variables as variablenames
            myConstraintTableNames = ["Metric.","Use This Metric At All?",...
                "Use < Constraint?", "Less than what?",...
                "Use = Constraint?", "Equal to what?",...
                "Use > Constraint?", "Greater than what?"];
            
            constraints_Table = table(app.MetricsEvaluated, UseTF,... 
                LessThanTF,LessThanVal,...
                EqualToTF, EqualToVal,...
                GreaterThanTF,GreaterThanVal,...
                VariableNames=myConstraintTableNames);


            %% Fill in the uitable 
            app.UITable_Constraints.Data = constraints_Table;
            %% Set utable properties
            app.UITable_Constraints.ColumnEditable = true;
            app.UITable_Constraints.Multiselect = true;
%             app.UITable_Constraints.ColumnWidth = 'fit';
            app.UITable_Constraints.FontSize = 18;
            app.UITable_Constraints.RowStriping ='on';
            %% Add style
            styleCentered = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable_Constraints, styleCentered, 'column', [2:width(app.UITable_Constraints.Data)] );

        end
        
        function getChannelMetrics(app)
            %% Clear table in case they choose new selection
            app.UITable_ChannelSelection.Data = [];
            app.UITable_ChannelSelection.ColumnName = [];
            app.UITable_ChannelSelection.RowName = [];
            %%
            %% At this point the user has selected the library of spds and the spds are loaded as a property.
            % Let's make metrics and plot them into the table. This is very
            % similar to the UITable_Constraints
            %% Get the peaks and full width half max of each channel
            % We cannot have uitable display cells in a column that have
            % different length, so we need to use strings instead. This is
            % not a big deal since we do not need to use this data in the
            % program, only display it.
%             app.ChannelsColNumSelected
%             size(app.userSPDLibrary)

            for nChannel = 1: width(app.userSPDLibrary)
                [~,chPkLocs_temp,chPkFwhm_temp] =...
                    findpeaks(app.userSPDLibrary(:,nChannel),...
                    app.wlUserImported,...
                    'WidthReference','halfheight','MinPeakDistance',20,'MinPeakWidth',10);
                chPkLocs{nChannel,1}(1,:) = string( round(chPkLocs_temp,1,"decimals") ).join(', ');
                chPkFwhm{nChannel,1}(1,:) = string( round(chPkFwhm_temp,1,"decimals") ).join(', ');
                clear chPkLocs_temp chPkFwhm_temp
            end
            %% Get wavelength centroid;
            for nChannel = 1:width(app.userSPDLibrary)
                % https://mathworld.wolfram.com/FunctionCentroid.html
                x = app.wlUserImported;
                y = app.userSPDLibrary(:,nChannel);                
                chCentroid_temp =trapz( x,x.*y)./ trapz(x,y);
                chCentroid{nChannel,1}(1,:) = string( round(chCentroid_temp,1,"decimals") ); %not needed, but for consistency
                clear chCentroid_temp
            end
            channelMetricNames = ["Peak";"FWHM";"Centroid"];
            channelNames = app.UITable_ImportedFile.ColumnName(app.ChannelsColNumSelected);
            tMetrics = cell2table([chPkLocs,chPkFwhm,chCentroid] ,...
                RowNames = channelNames,...
                VariableNames= channelMetricNames);
%             tUseNoUse = table( false(numel()))
            tUseNoUse = array2table(false( size(app.userSPDLibrary,2) ,1 ),...
                RowNames = channelNames,...
                VariableNames= "Use For Optimization?" );
            
            selectionTable = [tUseNoUse,tMetrics];
%             app.ChannelMetricTable = selectionTable;
            app.UITable_ChannelSelection.Data = selectionTable;
            app.UITable_ChannelSelection.RowName = selectionTable.Properties.RowNames;%["Use For Optimization?";channelMetricNames];
            app.UITable_ChannelSelection.ColumnName = selectionTable.Properties.VariableNames; %Not sure why this isn't inherited via .Data assignment
            app.UITable_ChannelSelection.ColumnEditable = [true, false(1,width( app.UITable_ChannelSelection.Data )-1 ) ];
            % Stylize
            horizAlignStyle = uistyle("HorizontalAlignment","center");
            addStyle(app.UITable_ChannelSelection,horizAlignStyle);

            %%

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% debug
            %% Load previous session's output folder
            try
                app.loadMyDefaults;
            catch
            end           
            %% Define folder where the output excel table goes
            [app.appRootPath] = fileparts( mfilename('fullpath') );
            %%
            hold(app.UIAxes_OptimSPD,'on');
            title(app.UIAxes_OptimSPD, "Most Recent SPD");
            xlabel(app.UIAxes_OptimSPD, "Wavelength (nm)");
            ylabel(app.UIAxes_OptimSPD, "Rad Watt / nm");
            %%
            hold(app.UIAxes_ImportedSPDs,'on');
            app.UIAxes_ImportedSPDs.XLim = [380,780];
            defXTick = app.UIAxes_ImportedSPDs.XTick;
            app.UIAxes_ImportedSPDs.XTick = linspace(defXTick(1), defXTick(end), 2*numel(defXTick)-1 );%double ticks          
% app.UIAxes_ImportedSPDs.XTick = (380:25:780);
            grid(app.UIAxes_ImportedSPDs,'on')
            %% Make Chromaticity Diagram    
            hold(app.UIAxes_ChromDiagram,'on');
            plotChromDiagram(2,app.UIAxes_ChromDiagram); %add a 2 deg chrom diagram to the axis handle
            pbaspect(app.UIAxes_ChromDiagram,[1 1 1]);
            %% Create Default Optimization Constraint Table
            app.makeUIConstraintTable; %helper function. It also assigns app.MetricsEvaluated property
            %% Set options for Optimization Goal Drop Downs
            app.DropDown_MetricGoal.Items = app.MetricsEvaluated;
            app.DropDown_MaxOrMinGoal.Items = {'Maximize','Minimize'};            
            %% Set default max iterations
            app.EditField_MaxIterations.Value = 500;

            %%

            t = array2table(ones(5,6),VariableNames = [string(1:6)])
            app.UITable2.Data = t;
        end

        % Button pushed function: Button_ImportTable
        function Button_ImportTablePushed(app, event)

            %% Prompt user for spreadsheet to import
            %https://www.youtube.com/watch?v=7_IC8Xcjp4w
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html#mw_fbfe34a8-3ccf-49de-b27f-ff95c81b212e
            importFileTypes = {'*.xls;*.xlsx;*.xlsb;*.xlsm;*.csv',...
                 'SPD Files (*.xls,*.xlsx,*.xlsb,*.xlsm,*.csv)'};
            [spdFileName,spdFolderPath] = uigetfile(importFileTypes); %have user select csv file
            %% Get full path of file
            spdFileFullPath           =  fullfile(spdFolderPath,spdFileName);
            app.importedFileName_Prop = spdFileName;
            %% Import spreadsheet into table.
            t = readtable(spdFileFullPath,...
                ReadVariableNames= true, VariableNamingRule= 'preserve');
            try sheetnames(spdFileFullPath)
                uialert(app.UIFigure, "File has multiple sheets. Loading Sheet 1", "Warning",'icon','warning');
            catch
            end
            %% Check that all values are positive, if not, warn and set to 0
            if any(t{:,:} < 0,'all') % check 'all' values to see if any are < 0
                % https://www.mathworks.com/help/matlab/ref/uialert.html
                [rowIdx, colIdx] = find(  t{:,:} < 0 ); %get row and column indices that are negative
                colList = unique(colIdx); %get the unique list of columns that had a negative
                message = [(string(numel(colIdx)) + " Entries were found to be negative");...
                    "The following columns (SPDs) had negatives: " + strjoin( string(colList), ',');... %this just makes a list like colList = [1,3,4] ---> "1, 3, 4"
                    "This/these value(s) will be set to 0 in the app";...
                    "(The file used to import data will not be changed)"];
                uialert(app.UIFigure, message,'Warning','Icon','warning');
                t{rowIdx,colIdx} = 0; %set the negative values to 0
            end
            %% Put the imported file name in the edit field
            
            app.UITable_ImportedFile.Data = t;
            app.UITable_ImportedFile.ColumnName = t.Properties.VariableNames;
            app.EditField_ImportedFileName.Value = "Data Loaded From "  + string(app.importedFileName_Prop);
        end

        % Button pushed function: Button_UseSelectedSPDs
        function Button_UseSelectedSPDsPushed(app, event)
            
            %% Select SPDs from selection

            % the .Selection queries the highlighted cells and returns an
            % n x 2 array of indices: [rows, cols]. Use this to create a
            % subtable and then convert that to an array
            cellSelection = app.UITable_ImportedFile.Selection;
            uniqueRow = unique(cellSelection(:,1) ); %note that unique automatically sorts in ascending order
            uniqueCol = unique(cellSelection(:,2) );
            % Assign property
            app.ChannelsColNumSelected = uniqueCol;
            %
            subTableSelected = app.UITable_ImportedFile.Data(uniqueRow,uniqueCol); %selected set of SPDs
            app.userSPDLibrary = table2array( subTableSelected ); %my functiosn expect arrays

            app.wlIntUserImported_Prop = (780-380)/(height(app.userSPDLibrary)-1);
            app.wlUserImported(:,1) = 380:app.wlIntUserImported_Prop:780;
            if round( app.wlIntUserImported_Prop) ~= app.wlIntUserImported_Prop
                msgbox( "When guessing wavelength interval, a non-integer was determined. "+...
                    "Are you sure the wavelengths are from 380 to 780 and that you selected the entire column?", 'Error','error')
            end
            % This Ensure they chose a square selection (not necessarily
            % contiguous). 
            if numel(app.userSPDLibrary) ~= size(cellSelection,1)
                msgbox("Please Ensure each SPD has the same amount of rows selected", 'Error','error');
            end

            %% Set the user spds to the selected subtable
            %%
            observerChoice = 2;
            [app.xyuserSPDLibrary,app.XYZuserSPDLibrary] = ...
                spdsToXyXYZ(app.userSPDLibrary(:,1:end), observerChoice );%get xy chromaticities for the imported spds
            %% Scale data Y10 = 100 for each channel
            % Find amount needed to scale each channel
            app.ScalePerSPD = 100./app.XYZuserSPDLibrary(:,2);
            % Create new set of SPDs that are scaled to Y10 = 100;
            app.SPDsScaled_Prop         = app.ScalePerSPD'.*app.userSPDLibrary; 
            % Create new set of XYZ for them scaled. xy should not change
            % via scaling
            [app.xySPDsScaled_Prop,app.XYZSPDsScaled_Prop] = spdsToXyXYZ(app.SPDsScaled_Prop(:,1:end) , 2 );%get xy chromaticities for the imported spds

            %% Set edit value text box to show file name and number of channels
            app.EditField_ImportedFileName.Value = num2str(width(app.userSPDLibrary)) + " channels were loaded from: "  + string(app.importedFileName_Prop);

            %% Plot the chrom diagram
            % This is verbose but might help understand code better Add the ...
            % xy coordinates of the spds to the chrom diagram plot
            scatter(app.UIAxes_ChromDiagram,...
                                    app.xyuserSPDLibrary(:,1),...
                                    app.xyuserSPDLibrary(:,2),...
                                    tag = "xySPDs",...
                                    sizeData = 100, ...
                                    color = 'k'); %plot them
            hold(app.UIAxes_ChromDiagram,'on');
            scatter(app.UIAxes_ChromDiagram,[],[],tag="filledxySPDs");
            %Put numbers inside of them
            strValues(:,1) = strtrim(cellstr(num2str([1:size(app.xyuserSPDLibrary,1)]' )) );
            xyText = text(app.UIAxes_ChromDiagram,...
                            app.xyuserSPDLibrary(:,1),app.xyuserSPDLibrary(:,2),strValues,...
                            'HorizontalAlignment','center',...
                            'VerticalAlignment','middle',...
                            'fontsize', 12);
            %% Plot raw imported data to spd figure
%             PlotSPDs_Helper(app);
            allSPDPlot = plot(app.UIAxes_ImportedSPDs,...
                380:app.wlIntUserImported_Prop:780,...
                app.userSPDLibrary(:,1:end),...
                LineStyle="--");
            app.UIAxes_ImportedSPDs.XLim = [380,780];

            %% Process spds and put into the selection table
            getChannelMetrics(app);
        end

        % Button pushed function: Button_RunOptimization
        function Button_RunOptimizationPushed(app, event)
            try                
                %% Run optimization
                [SpdMixOut, myOptimOptions,iterationStop,myUiFun] = RunOptimization(app);
                %% Store results into one scalar structure
                myOptimResults(1).metrics = SpdMixOut;
                myOptimResults(1).spdPercents0 = app.InitialGuessChannelPercents_Prop;
                myOptimResults(1).myUiFun = myUiFun;
                myOptimResults(1).myOptimOptions = myOptimOptions;
                myOptimResults(1).myConstraintsTable = app.UITable_Constraints.Data;
                myOptimResults(1).spdFileImportPath =  app.importedFileName_Prop;
                myOptimResults(1).iterationStop = iterationStop;
                %% Check that it is possible
                if min(myOptimResults(1).metrics.Solution < 0)
                    message = {'SPD Result Is Not All-Positive', 'No Data Will Be Saved Or Plotted', 'Try again'};
                    uialert(app.UIFigure,message,'Error','Icon','Error');
                    return
                end
                %% Display result and ask for selection
                %https://www.mathworks.com/help/matlab/ref/uiconfirm.html
                msg = ["Goal Metric, " + myUiFun.metric + ", is " + string(myOptimResults(1).metrics.(myUiFun.metric) );...
                    "Would you like to save this SPD?"];
                selection = uiconfirm(app.UIFigure, msg,"Save Results?", 'Options', {'Yes', 'No', 'Try More'} );
                switch selection
                    case 'Yes'
                        %WriteOptimResults also prompts user for where to
                        %save. Consider breaking up the function later
                        WriteOptimResults(app, myOptimResults); 
%                         PlotTM30VectorGraphic(app,myOptimResults);
%                         PlotRecentTM30Bins(app,myOptimResults);
%                         PlotOptimSPD(app, myOptimResults);
                    case 'No'
                        msg = ["You chose not to save the results"; "You can change the inputs and click 'run' again"];
                        uialert(app.UIFigure, msg, 'Unsatisfactory Results', 'Interpreter','html','icon','info');

                        return
                    case 'Try More'
                        error("Not Implemented")
                    otherwise
                        error("Case errorin selecting how to proceed with optimization results")
                end
                %%
            catch ME
                report = getReport(ME);
                uialert(app.UIFigure, report, 'Error Message', 'Interpreter','html')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.saveMyDefaults;

            delete(app)
            
        end

        % Cell edit callback: UITable_ChannelSelection
        function UITable_ChannelSelectionCellEdit(app, event)
            %% The only editable parts of the table should be the first column
            % Therefore, no indexing is needed. Just query the selected
            % spds
%             indices = event.Indices;
%             newData = event.NewData;
            useVec = app.UITable_ChannelSelection.Data{:,1};
            %% Update spd plots
            [app.UIAxes_ImportedSPDs.Children(~useVec).LineStyle] = deal('--');
            [app.UIAxes_ImportedSPDs.Children(useVec).LineWidth] = deal(.5);
%             [app.UIAxes_ImportedSPDs.Children(useVec).LineColor] = deal('k');

            [app.UIAxes_ImportedSPDs.Children(useVec).LineStyle] = deal('-');
            [app.UIAxes_ImportedSPDs.Children(useVec).LineWidth] = deal(2);
            %% Update cie diagram
            currentFilledxy = findobj(app.UIAxes_ChromDiagram,...
                tag= "filledxySPDs");
            currentFilledxy.MarkerFaceColor = [0 0.8 0]; %green
%             currentFilledxy.
            currentFilledxy.MarkerFaceAlpha = 0.25; 
            currentFilledxy.SizeData = 100;

            currentFilledxy.XData = app.xyuserSPDLibrary(useVec,1);
            currentFilledxy.YData = app.xyuserSPDLibrary(useVec,2);
%             scatter(app.UIAxes_ChromDiagram, app.xyuserSPDLibrary(useVec,1),...
%                 app.xyuserSPDLibrary(useVec,2),'filled','g','tag','filledxySPDs');
%             [app.UIAxes_ChromDiagram.Children]
%             a=1
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataImportTab = uitab(app.TabGroup);
            app.DataImportTab.Title = 'Data Import';
            app.GridLayout3 = uigridlayout(app.DataImportTab);
            app.Panel = uipanel(app.GridLayout3);
            app.Button_ImportTable = uibutton(app.Panel, 'push');
            app.Button_ImportTable.ButtonPushedFcn = createCallbackFcn(app, @Button_ImportTablePushed, true);
            app.Button_ImportTable.Text = 'Import Table';
            app.EditField_ImportedFileName = uieditfield(app.Panel, 'text');
            app.EditField_ImportedFileName.Placeholder = 'Imported File Name';
            app.Button_UseSelectedSPDs = uibutton(app.Panel, 'push');
            app.Button_UseSelectedSPDs.ButtonPushedFcn = createCallbackFcn(app, @Button_UseSelectedSPDsPushed, true);
            app.Button_UseSelectedSPDs.Text = 'Use Selected SPDs';
            app.UITable_ImportedFile = uitable(app.GridLayout3);
            app.UITable_ImportedFile.RowName = {};
            app.DataValidationTab = uitab(app.TabGroup);
            app.DataValidationTab.Title = 'Data Validation';
            app.GridLayout = uigridlayout(app.DataValidationTab);
            app.UIAxes_ImportedSPDs = uiaxes(app.GridLayout);
            title(app.UIAxes_ImportedSPDs, 'Imported SPD Channels')
            xlabel(app.UIAxes_ImportedSPDs, 'Wavelength (nm)')
            ylabel(app.UIAxes_ImportedSPDs, 'Radiant Watts / nm')
            zlabel(app.UIAxes_ImportedSPDs, 'Z')
            app.UIAxes_ChromDiagram = uiaxes(app.GridLayout);
            title(app.UIAxes_ChromDiagram, 'CIE Diagram')
            xlabel(app.UIAxes_ChromDiagram, 'x Chromaticity')
            ylabel(app.UIAxes_ChromDiagram, 'y Chromaticity')
            zlabel(app.UIAxes_ChromDiagram, 'Z')
            app.ChannelSelectionTablePanel = uipanel(app.GridLayout);
            app.ChannelSelectionTablePanel.Title = 'Channel Selection Table';
            app.UITable_ChannelSelection = uitable(app.ChannelSelectionTablePanel);
            app.UITable_ChannelSelection.RowName = {};
            app.UITable_ChannelSelection.CellEditCallback = createCallbackFcn(app, @UITable_ChannelSelectionCellEdit, true);
            app.OptimizationSettingsTab = uitab(app.TabGroup);
            app.OptimizationSettingsTab.Title = 'Optimization Settings';
            app.GridLayout2 = uigridlayout(app.OptimizationSettingsTab);
            app.OptimizationGoalPanel = uipanel(app.GridLayout2);
            app.OptimizationGoalPanel.Title = 'Optimization Goal';
            app.MaximizeOrMinimizeLabel = uilabel(app.OptimizationGoalPanel);
            app.MaximizeOrMinimizeLabel.Text = 'Maximize Or Minimize?';
            app.DropDown_MaxOrMinGoal = uidropdown(app.OptimizationGoalPanel);
            app.WhichMetricLabel = uilabel(app.OptimizationGoalPanel);
            app.WhichMetricLabel.Text = 'Which Metric?';
            app.DropDown_MetricGoal = uidropdown(app.OptimizationGoalPanel);
            app.MaxOptimizationIterationsLabel = uilabel(app.OptimizationGoalPanel);
            app.MaxOptimizationIterationsLabel.Text = 'Max Optimization Iterations';
            app.EditField_MaxIterations = uieditfield(app.OptimizationGoalPanel, 'numeric');
            app.EditField_MaxIterations.ValueDisplayFormat = '%.0f';
            app.Button_RunOptimization = uibutton(app.OptimizationGoalPanel, 'push');
            app.Button_RunOptimization.ButtonPushedFcn = createCallbackFcn(app, @Button_RunOptimizationPushed, true);
            app.Button_RunOptimization.Text = 'Run Optimization';
            app.OptimizationConstraintsPanel = uipanel(app.GridLayout2);
            app.OptimizationConstraintsPanel.Title = 'Optimization Constraints';
            app.UITable_Constraints = uitable(app.OptimizationConstraintsPanel);
            app.UITable_Constraints.RowName = {};
            app.OptimizationResultsTab = uitab(app.TabGroup);
            app.OptimizationResultsTab.Title = 'Optimization Results';
            app.Panel4 = uipanel(app.OptimizationResultsTab);
            app.Panel4.Title = 'Panel4';
            app.UIAxes_OptimSPD = uiaxes(app.Panel4);
            title(app.UIAxes_OptimSPD, 'Optimized SPD')
            xlabel(app.UIAxes_OptimSPD, 'X')
            ylabel(app.UIAxes_OptimSPD, 'Y')
            zlabel(app.UIAxes_OptimSPD, 'Z')
            app.UITable_OptimizationResults = uitable(app.Panel4);
            app.UITable_OptimizationResults.RowName = {};
            app.Button_ExportOptimization = uibutton(app.Panel4, 'push');
            app.Button_ExportOptimization.Text = 'Export Optimization';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout4 = uigridlayout(app.Tab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable2 = uitable(app.GridLayout4);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.CheckBox = uicheckbox(app.GridLayout4);
            app.Panel6 = uipanel(app.GridLayout4);
            app.Panel6.Title = 'Panel6';
            app.UIAxes2 = uiaxes(app.Panel6);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.Panel6);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Panel6);
            app.CheckBox2 = uicheckbox(app.Panel6);
            app.CheckBox2.Text = 'Check Box2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetametricApp_Beta

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end