classdef metametricApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ImportFileButton                matlab.ui.control.Button
        ImportedFileNameEditField       matlab.ui.control.EditField
        ImportedFileNameEditFieldLabel  matlab.ui.control.Label
        UIAxes_ChromaticityDiagram      matlab.ui.control.UIAxes
        UIAxes_Imported                 matlab.ui.control.UIAxes
        CenterPanel                     matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            %% Deal with ui callback
            %https://www.youtube.com/watch?v=7_IC8Xcjp4w
            [spdFileName,spdFolderPath] = uigetfile('*.xlsx');
            spdFileFullPath = strcat(spdFolderPath,'\',spdFileName); %will addresses always be \ or sometimes \\ or / or // ?
            app.ImportedFileNameEditField.Value = spdFileName;
            excelSheetName = "UserSPDs";

            %% Import the data
            [userSPDs, wlInt_user] = importUserSPDs(spdFileFullPath,excelSheetName);
            %% Plot Data
            plot(app.UIAxes_Imported, 380:wlInt_user:780, userSPDs(:,1:end));
            app.UIAxes_Imported.XLim = [380,780];
            %https://www.mathworks.com/help/matlab/ref/matlab.ui.control.uiaxes-properties.html#d123e1691862
            %instead of assigning here, I just preset the labels
%             app.UIAxes_Imported.XLabel.String = ("Wavelength (nm)");
%             app.UIAxes_Imported.YLabel.String = ("Rad Watts/nm");


        end

        % Value changed function: ImportedFileNameEditField
        function ImportedFileNameEditFieldValueChanged(app, event)
            value = app.ImportedFileNameEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {759, 759, 759};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {759, 759};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {394, '1x', 435};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes_Imported = uiaxes(app.LeftPanel);
            title(app.UIAxes_Imported, 'Imported SPDs')
            xlabel(app.UIAxes_Imported, 'Wavelength (nm)')
            ylabel(app.UIAxes_Imported, 'Rad Watts/nm')
            zlabel(app.UIAxes_Imported, 'Z')
            app.UIAxes_ChromaticityDiagram = uiaxes(app.LeftPanel);
            title(app.UIAxes_ChromaticityDiagram, 'Title')
            xlabel(app.UIAxes_ChromaticityDiagram, 'X')
            ylabel(app.UIAxes_ChromaticityDiagram, 'Y')
            zlabel(app.UIAxes_ChromaticityDiagram, 'Z')
            app.ImportedFileNameEditFieldLabel = uilabel(app.LeftPanel);
            app.ImportedFileNameEditFieldLabel.Text = {'Imported File'; ' Name'};
            app.ImportedFileNameEditField = uieditfield(app.LeftPanel, 'text');
            app.ImportedFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @ImportedFileNameEditFieldValueChanged, true);
            app.ImportedFileNameEditField.Editable = 'off';
            app.ImportFileButton = uibutton(app.LeftPanel, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metametricApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end