classdef testapp10622 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        ChannelImportTab              matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        ScaledImportButton            matlab.ui.control.Button
        RawImport                     matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        ImportSPDChannelsButton       matlab.ui.control.Button
        UIAxes_ImportedSPDs           matlab.ui.control.UIAxes
        UIAxes_ChromDiagram           matlab.ui.control.UIAxes
        OptimizationSettingsTab       matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        OptimizationSettingsLabel     matlab.ui.control.Label
        OptimizationConstraintsLabel  matlab.ui.control.Label
        OptimizationGoalLabel         matlab.ui.control.Label
        OptimizationResultsTab        matlab.ui.container.Tab
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        userSPDsProp; % Description
        XYZUserSPDsProp;
        importedFileNameProp;
        xyUserSPDsProp;
        wlInt_UserProp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportSPDChannelsButton
        function ImportSPDChannelsButtonPushed(app, event)

            %% Nothing here is shared outside of this function unless it is added to a property in the block below
            %%Deal with ui callback
            %https://www.youtube.com/watch?v=7_IC8Xcjp4w
            [spdFileName,spdFolderPath] = uigetfile('*.xlsx'); %have user select csv file
            spdFileFullPath = strcat(spdFolderPath,'\',spdFileName); %will addresses always be \ or sometimes \\ or / or // ?
            excelSheetName = "UserSPDs";

            %% Import the data
            [userSPDs, wlInt_User] = importUserSPDs(spdFileFullPath,excelSheetName);
            [xyUserSPDs,XYZUserSPDs] = spdsToXyXYZ(userSPDs(:,1:end), 2 );%get xy chromaticities for the imported spds

            %% Set edit value text box to show file name and number of channels
            app.EditField.Value = num2str(width(userSPDs)) + " channels were loaded from: "  + string(spdFileName);

            %% Set properties from imported values. These can be shared to other functions
            % most notably, to the raw import and scale import buttons
            app.userSPDsProp         = userSPDs; %give all functions access so it does not need to be imported again.
            app.XYZUserSPDsProp      = XYZUserSPDs;
            app.xyUserSPDsProp       = xyUserSPDs;
            app.importedFileNameProp = spdFileName;
            app.wlInt_UserProp = wlInt_User;

            %% Plot the chrom diagram
            PlotSPDChromDiagram;
            %% Plot raw imported data to spd figure
            PlotSPDs;
% %             %% Deal with ui callback
% %             %https://www.youtube.com/watch?v=7_IC8Xcjp4w
% %             [spdFileName,spdFolderPath] = uigetfile('*.xlsx'); %have user select csv file
% %             spdFileFullPath = strcat(spdFolderPath,'\',spdFileName); %will addresses always be \ or sometimes \\ or / or // ?
% % %             app.EditField.Value = spdFileName;
% %             excelSheetName = "UserSPDs";
% % 
% %             %% Import the data
% %             [userSPDs, wlInt_user] = importUserSPDs(spdFileFullPath,excelSheetName);
% %             %% Plot Data
% %             plot(app.UIAxes_ImportedSPDs, 380:wlInt_user:780, userSPDs(:,1:end));
% %             app.UIAxes_ImportedSPDs.XLim = [380,780];
% % 
% %             %% Put filename in textbox
% %             %adding newline doesn't seem to work...
% %             app.EditField.Value = num2str(width(userSPDs)) + " channels were loaded from: "  + string(spdFileName);
% %             
% %             %% Create Chromaticity Diagram and place channel chromaticities
% %             [xyUserSPDs,XYZUserSPDs] = spdsToXyXYZ(userSPDs(:,1:end), 2 );%get xy chromaticities for the imported spds
% %             scatter(app.UIAxes_ChromDiagram, xyUserSPDs(:,1), xyUserSPDs(:,2) ); %plot them
% % 
% %             hold(app.UIAxes_ChromDiagram,'on'); %Using hold on by itself will NOT work https://www.mathworks.com/help/matlab/ref/uiaxes.html
% %             pbaspect(app.UIAxes_ChromDiagram,[1 1 1]);
% % 
% %             plotChromDiagram(2,app.UIAxes_ChromDiagram); %add a 2 deg chrom diagram to the axis handle
        end

        % Button pushed function: RawImport
        function RawImportButtonPushed(app, event)
            hold(app.UIAxes_ImportedSPDs,'off'); %replace

            plot(app.UIAxes_ImportedSPDs, 380:app.wlInt_UserProp:780, app.userSPDsProp(:,1:end));
            app.UIAxes_ImportedSPDs.XLim = [380,780];
        end

        % Button pushed function: ScaledImportButton
        function ScaledImportButtonPushed(app, event)
%             ImportAndPlotSPDs; 
            hold(app.UIAxes_ImportedSPDs,'off'); %replace

            scalePerSPD_Temp = 100./app.XYZUserSPDsProp(:,2);
            userSPDsScaled = scalePerSPD_Temp'.*app.userSPDsProp; 

            plot(app.UIAxes_ImportedSPDs, 380:app.wlInt_UserProp:780, userSPDsScaled(:,1:end));
            app.UIAxes_ImportedSPDs.XLim = [380,780];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChannelImportTab = uitab(app.TabGroup);
            app.ChannelImportTab.Title = 'Channel Import';
            app.GridLayout = uigridlayout(app.ChannelImportTab);
            app.UIAxes_ChromDiagram = uiaxes(app.GridLayout);
            title(app.UIAxes_ChromDiagram, 'CIE Diagram')
            xlabel(app.UIAxes_ChromDiagram, 'x Chromaticity')
            ylabel(app.UIAxes_ChromDiagram, 'y Chromaticity')
            zlabel(app.UIAxes_ChromDiagram, 'Z')
            app.UIAxes_ImportedSPDs = uiaxes(app.GridLayout);
            title(app.UIAxes_ImportedSPDs, 'Imported SPD Channels')
            xlabel(app.UIAxes_ImportedSPDs, 'Wavelength (nm)')
            ylabel(app.UIAxes_ImportedSPDs, 'Radiant Watts / nm')
            zlabel(app.UIAxes_ImportedSPDs, 'Z')
            app.ImportSPDChannelsButton = uibutton(app.GridLayout, 'push');
            app.ImportSPDChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSPDChannelsButtonPushed, true);
            app.ImportSPDChannelsButton.Text = 'Import SPD Channels';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Placeholder = 'Imported File Name';
            app.RawImport = uibutton(app.GridLayout, 'push');
            app.RawImport.ButtonPushedFcn = createCallbackFcn(app, @RawImportButtonPushed, true);
            app.RawImport.Text = 'RawImport';
            app.ScaledImportButton = uibutton(app.GridLayout, 'push');
            app.ScaledImportButton.ButtonPushedFcn = createCallbackFcn(app, @ScaledImportButtonPushed, true);
            app.ScaledImportButton.Text = 'ScaledImport';
            app.OptimizationSettingsTab = uitab(app.TabGroup);
            app.OptimizationSettingsTab.Title = 'Optimization Settings';
            app.GridLayout2 = uigridlayout(app.OptimizationSettingsTab);
            app.OptimizationGoalLabel = uilabel(app.GridLayout2);
            app.OptimizationGoalLabel.Text = 'Optimization Goal';
            app.OptimizationConstraintsLabel = uilabel(app.GridLayout2);
            app.OptimizationConstraintsLabel.Text = 'Optimization Constraints';
            app.OptimizationSettingsLabel = uilabel(app.GridLayout2);
            app.OptimizationSettingsLabel.Text = 'Optimization Settings';
            app.OptimizationResultsTab = uitab(app.TabGroup);
            app.OptimizationResultsTab.Title = 'Optimization Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp10622

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end